classdef error_calcs_cylinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FindanswerButton          matlab.ui.control.Button
        V1mm2NEditFieldLabel      matlab.ui.control.Label
        V1mm2NEditField           matlab.ui.control.NumericEditField
        V2mm2NEditFieldLabel      matlab.ui.control.Label
        V2mm2NEditField           matlab.ui.control.NumericEditField
        ForceNEditFieldLabel      matlab.ui.control.Label
        ForceNEditField           matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel  matlab.ui.control.Label
        DiametermmEditField       matlab.ui.control.NumericEditField
        ErrormmEditFieldLabel     matlab.ui.control.Label
        ErrormmEditField          matlab.ui.control.NumericEditField
        CalculatingerrorsduetomeasuringforceLabel  matlab.ui.control.Label
        CylinderincontactwithaplaneLabel  matlab.ui.control.Label
        LengthofcylinderincontactmmEditFieldLabel  matlab.ui.control.Label
        LengthofcylinderincontactmmEditField  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindanswerButton
        function FindanswerButtonPushed(app, event)
            V1=app.V1mm2NEditField.Value;
            V2=app.V2mm2NEditField.Value;
            P =app.ForceNEditField.Value;
            D =app.DiametermmEditField.Value;
            a=app.LengthofcylinderincontactmmEditField.Value/2;
            P_bar = P/(2*a);
            
                alpha = (log((8*a^2)/((V1+V2)*P_bar*D))+1)*(V1+V2)*P_bar*2;
  
        app.ErrormmEditField.Value= alpha;
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FindanswerButton = uibutton(app.UIFigure, 'push');
            app.FindanswerButton.ButtonPushedFcn = createCallbackFcn(app, @FindanswerButtonPushed, true);
            app.FindanswerButton.Text = 'Find answer';
            app.V1mm2NEditFieldLabel = uilabel(app.UIFigure);
            app.V1mm2NEditFieldLabel.Text = 'V1 (mm^2/N)';
            app.V1mm2NEditField = uieditfield(app.UIFigure, 'numeric');
            app.V2mm2NEditFieldLabel = uilabel(app.UIFigure);
            app.V2mm2NEditFieldLabel.Text = 'V2 (mm^2/N)';
            app.V2mm2NEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceNEditFieldLabel = uilabel(app.UIFigure);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametermmEditFieldLabel = uilabel(app.UIFigure);
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.DiametermmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrormmEditFieldLabel = uilabel(app.UIFigure);
            app.ErrormmEditFieldLabel.Text = 'Error (mm)';
            app.ErrormmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatingerrorsduetomeasuringforceLabel = uilabel(app.UIFigure);
            app.CalculatingerrorsduetomeasuringforceLabel.Text = 'Calculating errors due to measuring force';
            app.CylinderincontactwithaplaneLabel = uilabel(app.UIFigure);
            app.CylinderincontactwithaplaneLabel.Text = 'Cylinder in contact with a plane';
            app.LengthofcylinderincontactmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofcylinderincontactmmEditFieldLabel.Text = 'Length of cylinder in contact (mm)';
            app.LengthofcylinderincontactmmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = error_calcs_cylinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end